classdef DataLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DAQsListBox                     matlab.ui.control.ListBox
        DAQsListBoxLabel                matlab.ui.control.Label
        ArmDataAcquisitionPanel         matlab.ui.container.Panel
        ArmDataAcquisitionSwitch        matlab.ui.control.Switch
        TriggerControlsPanel            matlab.ui.container.Panel
        TrigModeSwitch                  matlab.ui.control.Switch
        TabGroup                        matlab.ui.container.TabGroup
        ExtTrigTab                      matlab.ui.container.Tab
        SamplesposttriggerEditField     matlab.ui.control.NumericEditField
        SamplesposttriggerEditFieldLabel  matlab.ui.control.Label
        ClickTrigTab                    matlab.ui.container.Tab
        TriggerButton                   matlab.ui.control.StateButton
        RateTimeControlsPanel           matlab.ui.container.Panel
        RecordTimesEditField            matlab.ui.control.NumericEditField
        RecordTimesEditFieldLabel       matlab.ui.control.Label
        PlotRateHzEditField             matlab.ui.control.NumericEditField
        PlotRateHzEditFieldLabel        matlab.ui.control.Label
        DAQRateHzEditField              matlab.ui.control.NumericEditField
        DAQRateHzEditFieldLabel         matlab.ui.control.Label
        FileSavingParametersPanel       matlab.ui.container.Panel
        FolderNote                      matlab.ui.control.Label
        FolderButton                    matlab.ui.control.Button
        ProjectFolderEditField          matlab.ui.control.EditField
        ProjectFolderEditFieldLabel     matlab.ui.control.Label
        FilenameoptionalEditField       matlab.ui.control.EditField
        FilenameoptionalEditFieldLabel  matlab.ui.control.Label
        mainPlot                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dn      % Names of recording DAQs (used to name fields for everything)
        s       % DAQ session 
        data    % Input data matrix
        name    % Filenames and folders
        channels% Input channel info
        rate    % Relevant information for timing/rates
        plotTimer
        lh  % Listeners for input
        newPanels
        channelPanels
    end

    methods (Access = private)
    
        function GrabData(app,event,info)
            %Append matrices with new data
            app.data.time = [app.data.time;info.TimeStamps];
            app.data.data = [app.data.data;info.Data];
            % trim to rolling buffer size
            if length(app.data.time) >= app.rate.buffer
                app.data.time = app.data.time(end-app.rate.buffer+1:end);
                app.data.data = app.data.data(end-app.rate.buffer+1:end,:);
            end
        end
        
    
        function plotTimerUpdateFcn(app,src,event)
            %Update Plots
            if ~isempty(app.data.time)
                plot(app.mainPlot, app.data.time, app.data.data)
            end
        end
    

        function getChannelActiveState(app, daq)
            app.channels.(daq).active = zeros(1, app.channels.(daq).howMany);
            for i = 1:app.channels.(daq).howMany
                app.channels.(daq).active(i) = strcmp(app.channels.(daq).(['chanOn_',num2str(i)]).Value, 'On');
            end
        end
        function getChannelDisplayState(app, daq)
            app.channels.(daq).display = zeros(1, app.channels.(daq).howMany);
            for i = 1:app.channels.(daq).howMany
                app.channels.(daq).display(i) = app.channels.(daq).(['chanDisplay_',num2str(i)]).Value;
            end
        end

        function createChannelPanel(app, index, numInit)
            % Create panel 
            app.channelPanels.(app.dn{index}) = uipanel(app.UIFigure, ...
                'Title', [app.dn{index}, ' channels'], ...
                'Position', [45 + (215 + 10)*(index-1), 0, 215, 400], ...
                'Scrollable', 'on', ...
                'Tag', num2str(index));
            % Create DAQ name field
            app.channels.(app.dn{index}).DAQNameField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'text', ...
                'Position', [0,360,80,22], ...
                'Value', app.dn{index}, ...
                'Tooltip', {'Name of this DAQ','Data will be saved under file with this name'});
            % Create "Number of channels" numeric field
            app.channels.(app.dn{index}).NumberofChannelsEditField = uieditfield(app.channelPanels.(app.dn{index}), ...
                'numeric', ...
                'Position', [135,335,31,22], ...
                'Value', numInit, ...
                'ValueChangedFcn', createCallbackFcn(app, @ChangeNumChannelsFcn, true));
            % Create trigger selection button group
            height = 25*numInit + 10 + 2*app.channels.trigOffset;
            app.channels.(app.dn{index}).TrigButtonGroup = uibuttongroup(app.channelPanels.(app.dn{index}), ...
                'Position', [177, 400-height-54, 25, height], 'Title', 'Trig', 'FontSize', 10, ...
                'AutoResizeChildren', 'off');
            % Create global check or uncheck all box
            app.channels.(app.dn{index}).globalCheck = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 320, 25, 25], ...
                    'Value', 1, 'Text', '', ...
                    'ValueChangedFcn', createCallbackFcn(app, @CheckBoxValueChanged, true), ...
                    'Tooltip', 'Check or uncheck display status for all channels on this DAQ');
            % Create each channel
            for i = 1:numInit
                app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                    'Items', {'Off', 'On'}, 'Value', 'On', ...
                    'Position', [25, 308 - 25*(i-1), 23, 10]);
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                    'Position', [78, 300 - 25*(i-1), 25, 25], ...
                    'Value', 1, 'Text', '');
                app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                    'text', 'Value', ['ch',num2str(i)], ...
                    'Position', [102, 300 - 25*(i-1), 69, 20]);
                app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(numInit-i), 16, 16], 'Text', '');
            end
            % Create labels
            app.channels.(app.dn{index}).NumberofChannelsEditFieldLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Number of Channels:', 'Position', [9,337,119,22]);
            app.channels.(app.dn{index}).EnableLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Enable', 'Position', [22,318,42,22], 'FontSize', 10);
            app.channels.(app.dn{index}).ChannelNameLabel = uilabel(app.channelPanels.(app.dn{index}), ...
                'Text', 'Channel Name', 'Position', [100,318,85,22], 'FontSize', 10);
            % Note how many channels
            app.channels.(app.dn{index}).howMany = numInit;
        end

        % Function to manually move trigger select buttons vertically 
        function resizeTrigButtons(app, panel, ncurrent, ntotal)
            for i = 1:ncurrent
                app.channels.(panel).(['toggleButton_',num2str(i)]).Position(2) = app.channels.trigOffset+25*(ntotal-i);
            end
        end


        % Function to save data, restart DAQ listeners and other processes,
        % reset app state for next recording
        function stopRecordingReset(app)
            for dq = app.DAQsListBox.Value
                i = dq{1};
                %Delete listeners
                delete(app.lh.(i))
                %Stop sessions
                stop(app.s.(i))
                % Get channel names to save
                app.name.(i).chanNames = cell(1, app.channels.(i).howMany);
                for j = 1:app.channels.(i).howMany
                    app.name.(i).chanNames{j} = app.channels.(i).(['chanName_',num2str(j)]).Value;
                end
                % Write data to file
                save(app.name.(i).filename, app.data.(i).data, app.name.(i).chanNames)
                % Re-enable controls
                app.channels.(i).TrigButtonGroup.Enable = 'On';
                for j = 1:app.channels.(i).howMany
                    app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'On';
                end
                % Clear out, re-declare matrices and indices
                app.data.(i).time = [];
                app.data.(i).data = [];

            end
            % Stop Plot Timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            
            % Reset GUI, re-enable everything
            app.ArmDataAcquisitionSwitch.Value = '';
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.ArmDataAcquisitionSwitch.FontWeight = 'normal';
            app.ArmDataAcquisitionSwitch.FontColor = [0,0,0];
            app.TriggerButton.Enable = 'Off';
            app.TriggerButton.Value = false;
            app.ProjectFolderEditField.Enable = 'On';
            app.FilenameoptionalEditField.Enable = 'On';
            app.FolderButton.Enable = 'On';
            app.DAQRateHzEditField.Enable = 'On';
            app.PlotRateHzEditField.Enable = 'On';
            app.RecordTimesEditField.Enable = 'On';
            app.TrigModeSwitch.Enable = 'On';
            app.DAQsListBox.Enable = 'On';
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Positioning constants
            app.channels.trigOffset = 8; %px

            % Populate DAQs list box based on active and connected DAQs
            % TODO: Likely use "daqlist", grab deviceIDs
            app.DAQsListBox.Items = {'Dev1', 'Dev2', 'Dev3', 'Dev4'};
            % For the first connected+active DAQ, run channel panel creation
            app.DAQsListBox.Value = {'Dev1'};
            app.dn = app.DAQsListBox.Items(1);
            createChannelPanel(app, 1, 10);
            app.channels.indexAvailable = [2];
            
        end

        % Button pushed function: FolderButton
        function FolderSelect(app, event)
            % Let the user select the folder to store data
            dialogTitle = 'Select directory for saving data';
            folderName = uigetdir(pwd,dialogTitle);
            app.ProjectFolderEditField.Value = folderName;
            % Enable DAQ arming, remove note
            app.ArmDataAcquisitionSwitch.Enable = 'On';
            app.FolderNote.Visible = 'Off';
        end

        % Value changed function: TriggerButton
        function StartLoggingSession(app, event)
            value = app.TriggerButton.Value;
            if value
                %Disable file selectors, BeginLoggingButton on GUI
                app.TriggerButton.Enable = 'Off';
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameoptionalEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                % Reset GUI, save data (if any), and stop data recording
                stopRecordingReset(app)
            end
        end

        % Value changed function: ArmDataAcquisitionSwitch
        function ArmDataAcquisitionSwitchValueChanged(app, event)
            value = app.ArmDataAcquisitionSwitch.Value;
            
            %Actions to do when data acquisition has been armed
            if strcmp(value,'Armed')
                % Start up and preallocate DAQ 
%                 daqreset;
                %Declare Rates 
                app.rate.DAQ = app.DAQRateHzEditField.Value; %(Hz)
                app.rate.plot = app.PlotRateHzEditField.Value; %(Hz)
                app.rate.plotLength = app.RecordTimesEditField.Value; %(s)
                app.rate.buffer = app.rate.plotLength * app.rate.DAQ; %(samples)
                % Change looks of data acquisition switch
                app.ArmDataAcquisitionSwitch.FontWeight = 'bold';
                app.ArmDataAcquisitionSwitch.FontColor = [0.635,0.078,0.184];
                % Make folder in chosen directory for today's date (if not already there)
                app.name.projFolder = app.ProjectFolderEditField.Value;
                app.name.path = strcat(app.name.projFolder, filesep, datestr(now,'yyyy_mm_dd'));
                if ~exist(app.name.path, 'dir')
                    mkdir(app.name.projFolder, datestr(now,'yyyy_mm_dd'))
                end
                % Loop over active DAQs
                for dq = app.DAQsListBox.Value
                    i = dq{1};
                    % Get which channels are active and should display
                    getChannelActiveState(app, i)
                    getChannelDisplayState(app, i)
                    % Initialize filename of this test
                    if ~isempty(app.FilenameoptionalEditField.Value)
                        app.name.(i).filename = [app.channels.(i).DAQNameField.Value, '_', app.FilenameoptionalEditField.Value, '.mat'];
                    else
                        app.name.(i).filename = [app.channels.(i).DAQNameField.Value, '_', datetime('now', 'format', 'yyyy_MM_dd'), '.mat'];
                    end
                    % Increment filename with numbers if same file already exists
                    if exist(fullfile(app.name.path, app.name.(i).filename), 'file')
                        % If no 3-digit number on end of file, add number
                        if isnan(str2double(app.name.(i).filename(end-6:end-4)))
                            app.name.(i).filename = [app.name.(i).filename(1:end-4), '_001', app.name.(i).filename(end-3:end)];
                            app.FilenameoptionalEditField.Value = app.name.filename(1:end-4);
                        % Otherwise grab and increment number
                        else
                            number = str2double(app.name.(i).filename(end-6:end-4));
                            app.name.(i).filename = [app.name.(i).filename(1:end-6), num2str(number+1,'%03.f'), app.name.(i).filename(end-3:end)];
                            app.FilenameoptionalEditField.Value = app.name.filename(1:end-4);
                    end
                    % Predeclare data
                    % TODO: actually preallocate as zeros to max length
                    % (Tricky because of how packets of data come in unexpectedly)
                    app.data.(i).time = [];
                    app.data.(i).data = [];
                    % Disable controls
                    app.channels.(i).TrigButtonGroup.Enable = 'Off';
                    for j = 1:app.channels.(i).howMany
                        app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'Off';
                    end
                    % Create DAQ session
                    app.s.(i) = daq('ni');
                    app.s.(i).Rate = app.rate.DAQ;
                    % Create channels
                    % TODO: With actual DAQ set up new system. Hard to say if
                    % it'll allow s1.addAnalogInputChannel as opposed to addAnalogInputChannel(s1, ...)
                    % https://www.mathworks.com/help/daq/transition-your-code-from-session-to-dataacquisition-interface.html
                    app.s.(i).addAnalogInputChannel(i, [find(app.channels.active)-1], 'Voltage');
                    %Create input/output listeners
                    app.lh.(i) = app.s.(i).addlistener('DataAvailable', @app.GrabData);

                    % Disable fields that shouldn't change during recording
                    app.channels.(i).TrigButtonGroup.Enable = 'Off';
                    for j = 1:app.channels.(i).howMany
                        app.channels.(i).(['chanOn_',num2str(j)]).Enable = 'Off';
                    end
                end
                
                % Create Plotting Timer
                app.rate.plot = app.PlotRateHzEditField.Value;
                app.plotTimer = timer('TimerFcn',{@app.plotTimerUpdateFcn},...
                    'ExecutionMode','fixedRate','busymode','queue',...
                    'Period',1/app.rate.plot,'Name','timer-1');
                start(app.plotTimer);
                
                % Disable fields that shouldn't change during recording
                app.ProjectFolderEditField.Enable = 'Off';
                app.FilenameoptionalEditField.Enable = 'Off';
                app.FolderButton.Enable = 'Off';
                app.DAQRateHzEditField.Enable = 'Off';
                app.PlotRateHzEditField.Enable = 'Off';
                app.RecordTimesEditField.Enable = 'Off';
                app.TrigModeSwitch.Enable = 'Off';
                app.DAQsListBox.Enable = 'Off';
                

                % Start main session(s)
                for dq = app.DAQsListBox.Value
                    i = dq{1};
                    startBackground(app.s.(i))
                end
           
            % Actions to do when switch is disarmed
            else
                stopRecordingReset(app)
            end

            % Enable data logging if data acq. has been armed and folder specified
            if ~isempty(app.ProjectFolderEditField.Value) && strcmp(value,'Armed')
                app.TriggerButton.Enable = 'On';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            % Stop sessions, catch errors in case they didn't exist or were already killed
            try
                for dq = app.DAQsListBox.Value
                    i = dq{1};
                    stop(app.s.(i))
                end
            catch
            end
            % Delete Listeners
            for dq = app.DAQsListBox.Value
                i = dq{1};
                delete(app.lh.(i))
            end
            %Stop timers
            if strcmp(get(app.plotTimer,'Running'),'on')
                stop(app.plotTimer)
            end
            
            delete(app)
        end

        % Value changed function: TrigModeSwitch
        function TrigModeChangedFcn(app, event)
            value = app.TrigModeSwitch.Value;
            app.TabGroup.SelectedTab = app.(value);
            if strcmp(value, 'ExtTrigTab')
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'on';
                end
            else
                for i = find(cellfun(@(x) ~isempty(x), app.dn))
                    app.channels.(app.dn{i}).TrigButtonGroup.Visible = 'off';
                end
            end
        end

        % Callback function
        function ChangeNumChannelsFcn(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % If new # of channels less than current, remove some channels
            if value < app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                % TODO: Make actual references to channel panel dimensions
                % (rather than hard-coded 400 constant)
                % Loop over channels to remove
                for i = (value+1):app.channels.(app.dn{index}).howMany
                    delete(app.channels.(app.dn{index}).(['chanOn_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['chanName_',num2str(i)]))
                    delete(app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]))
                end
                resizeTrigButtons(app, app.dn{index}, value, value)
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
                
            % If new number more than current, add some channels
            elseif value > app.channels.(app.dn{index}).howMany
                % Resize trigger channel selector
                height = 25*value + 10 + 2*app.channels.trigOffset;
                app.channels.(app.dn{index}).TrigButtonGroup.Position = [177, 400-height-54, 25, height];
                resizeTrigButtons(app, app.dn{index}, app.channels.(app.dn{index}).howMany, value)
                % Loop over channels to create
                for i = (app.channels.(app.dn{index}).howMany+1):value
                    app.channels.(app.dn{index}).(['chanOn_',num2str(i)]) = uiswitch(app.channelPanels.(app.dn{index}), 'slider', ...
                        'Items', {'Off', 'On'}, 'Value', 'On', ...
                        'Position', [25, 308 - 25*(i-1), 23, 10]);
                    app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]) = uicheckbox(app.channelPanels.(app.dn{index}), ...
                        'Position', [78, 300 - 25*(i-1), 25, 25], ...
                        'Value', 1, 'Text', '');
                    app.channels.(app.dn{index}).(['chanName_',num2str(i)]) = uieditfield(app.channelPanels.(app.dn{index}), ...
                        'text', 'Value', ['ch',num2str(i)], ...
                        'Position', [102, 300 - 25*(i-1), 69, 20]);
                    app.channels.(app.dn{index}).(['toggleButton_',num2str(i)]) = uitogglebutton(app.channels.(app.dn{index}).TrigButtonGroup, ...
                        'Position', [5, app.channels.trigOffset+25*(value-i), 16, 16], 'Text', '');
                end
                % Update how many channels
                app.channels.(app.dn{index}).howMany = value;
            end
            % If same number of channels, do nothing
        end

        % Value changed function: DAQsListBox
        function NewDAQFcn(app, event)
            % Get removed DAQs, call remove channel function on them
            removedDAQs = event.PreviousValue(~contains(event.PreviousValue, event.Value));
            for i = 1:length(removedDAQs)
                % Delete
                delete(app.channelPanels.(removedDAQs{i}))
                % clear out to allow new boxes to take this location
                index = find(strcmp(app.dn, removedDAQs(i)));
                app.dn{index} = '';
                app.channels.indexAvailable(end+1) = index;
            end
            
            % Get new DAQs, call create channel function on them
            newDAQs = event.Value(~contains(event.Value, event.PreviousValue));
            for i = 1:length(newDAQs)
                % If adding this channel panel to end
                if length(app.channels.indexAvailable) == 1
                    index = app.channels.indexAvailable(1);
                    app.channels.indexAvailable = app.channels.indexAvailable + 1;
                    app.dn{end+1} = newDAQs{i};
                % If putting in open middle slot
                else
                    sortAvailable = sort(app.channels.indexAvailable);
                    index = sortAvailable(1);
                    app.channels.indexAvailable(app.channels.indexAvailable==index) = [];
                    app.dn{index} = newDAQs{i};
                end
                % Create panel
                createChannelPanel(app, index, 10);
            end

        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            value = event.Value;
            % Check Tag to know which channel panel to do stuff to
            index = str2double(event.Source.Parent.Tag);
            % Loop over number of channels, check or uncheck all
            for i = 1:app.channels.(app.dn{index}).howMany
                app.channels.(app.dn{index}).(['chanDisplay_',num2str(i)]).Value = value;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.mainPlot = uiaxes(app.UIFigure);
            xlabel(app.mainPlot, 'Time (s)')
            ylabel(app.mainPlot, 'Voltage')
            app.FileSavingParametersPanel = uipanel(app.UIFigure);
            app.FileSavingParametersPanel.Title = 'File Saving Parameters';
            app.FilenameoptionalEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.FilenameoptionalEditFieldLabel.Text = 'Filename (optional)';
            app.FilenameoptionalEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.ProjectFolderEditFieldLabel = uilabel(app.FileSavingParametersPanel);
            app.ProjectFolderEditFieldLabel.Text = 'Project Folder';
            app.ProjectFolderEditField = uieditfield(app.FileSavingParametersPanel, 'text');
            app.FolderButton = uibutton(app.FileSavingParametersPanel, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelect, true);
            app.FolderButton.Text = '...';
            app.FolderNote = uilabel(app.FileSavingParametersPanel);
            app.FolderNote.Text = 'Note: Project Folder must be selected to start acquiring data!';
            app.RateTimeControlsPanel = uipanel(app.UIFigure);
            app.RateTimeControlsPanel.Title = 'Rate/Time Controls';
            app.DAQRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.DAQRateHzEditFieldLabel.Text = 'DAQ Rate (Hz)';
            app.DAQRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.DAQRateHzEditField.Value = 10000;
            app.PlotRateHzEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.PlotRateHzEditFieldLabel.Text = 'Plot Rate (Hz)';
            app.PlotRateHzEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.PlotRateHzEditField.Value = 2;
            app.RecordTimesEditFieldLabel = uilabel(app.RateTimeControlsPanel);
            app.RecordTimesEditFieldLabel.Text = 'Record Time (s)';
            app.RecordTimesEditField = uieditfield(app.RateTimeControlsPanel, 'numeric');
            app.RecordTimesEditField.Value = 20;
            app.TriggerControlsPanel = uipanel(app.UIFigure);
            app.TriggerControlsPanel.Title = 'Trigger Controls';
            app.TabGroup = uitabgroup(app.TriggerControlsPanel);
            app.ExtTrigTab = uitab(app.TabGroup);
            app.ExtTrigTab.Title = 'Ext Trig';
            app.SamplesposttriggerEditFieldLabel = uilabel(app.ExtTrigTab);
            app.SamplesposttriggerEditFieldLabel.Text = 'Samples post-trigger';
            app.SamplesposttriggerEditField = uieditfield(app.ExtTrigTab, 'numeric');
            app.SamplesposttriggerEditField.Value = 15;
            app.ClickTrigTab = uitab(app.TabGroup);
            app.ClickTrigTab.Title = 'Click Trig';
            app.TriggerButton = uibutton(app.ClickTrigTab, 'state');
            app.TriggerButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingSession, true);
            app.TriggerButton.Enable = 'off';
            app.TriggerButton.Text = 'Trigger';
            app.TrigModeSwitch = uiswitch(app.TriggerControlsPanel, 'slider');
            app.TrigModeSwitch.Items = {'External', 'Click Button'};
            app.TrigModeSwitch.ItemsData = {'ExtTrigTab', 'ClickTrigTab'};
            app.TrigModeSwitch.ValueChangedFcn = createCallbackFcn(app, @TrigModeChangedFcn, true);
            app.TrigModeSwitch.Value = 'ExtTrigTab';
            app.ArmDataAcquisitionPanel = uipanel(app.UIFigure);
            app.ArmDataAcquisitionPanel.Title = 'Arm Data Acquisition';
            app.ArmDataAcquisitionSwitch = uiswitch(app.ArmDataAcquisitionPanel, 'slider');
            app.ArmDataAcquisitionSwitch.Items = {'', 'Armed'};
            app.ArmDataAcquisitionSwitch.ValueChangedFcn = createCallbackFcn(app, @ArmDataAcquisitionSwitchValueChanged, true);
            app.ArmDataAcquisitionSwitch.Enable = 'off';
            app.ArmDataAcquisitionSwitch.Value = '';
            app.DAQsListBoxLabel = uilabel(app.UIFigure);
            app.DAQsListBoxLabel.Text = 'DAQs';
            app.DAQsListBox = uilistbox(app.UIFigure);
            app.DAQsListBox.Items = {};
            app.DAQsListBox.Multiselect = 'on';
            app.DAQsListBox.ValueChangedFcn = createCallbackFcn(app, @NewDAQFcn, true);
            app.DAQsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataLogger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end